classdef Task4GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        FontColourDropDown            matlab.ui.control.DropDown
        FontColourDropDownLabel       matlab.ui.control.Label
        FontSizeofPiSlider            matlab.ui.control.Slider
        FontSizeofPiSliderLabel       matlab.ui.control.Label
        SimulationControlsPanel       matlab.ui.container.Panel
        ClearGraphButton              matlab.ui.control.Button
        EsimatedPiValueTextArea       matlab.ui.control.TextArea
        EsimatedPiValueTextAreaLabel  matlab.ui.control.Label
        GenerateResultButton          matlab.ui.control.Button
        WidthOfPlanksEditField        matlab.ui.control.NumericEditField
        WidthOfPlanksEditFieldLabel   matlab.ui.control.Label
        LengthOfNeedleEditField       matlab.ui.control.NumericEditField
        LengthOfNeedleEditFieldLabel  matlab.ui.control.Label
        SlectTaskDropDown             matlab.ui.control.DropDown
        SlectTaskDropDownLabel        matlab.ui.control.Label
        NumberOfThrowsEditField       matlab.ui.control.NumericEditField
        NumberOfThrowsEditFieldLabel  matlab.ui.control.Label
        NumberOfPlanksEditField       matlab.ui.control.NumericEditField
        NumberOfPlanksEditFieldLabel  matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.LengthOfNeedleEditField.Value = randi([1, 9]);
            needleLength = app.LengthOfNeedleEditField.Value;
            app.WidthOfPlanksEditField.Value = 10;
            width = app.WidthOfPlanksEditField.Value;
            app.NumberOfThrowsEditField.Value = randi([1, 1000]);
            throws = app.NumberOfThrowsEditField.Value;
            app.NumberOfPlanksEditField.Value = randi([1, 10]);
            NumberOfFloorboards = app.NumberOfPlanksEditField.Value;
            maxBoundary = width * NumberOfFloorboards;

            hold(app.UIAxes, "on");
            [p,crossings, x1Array, y1Array, x2Array, y2Array, numeric_array] = Task4NeedleProblem(width, needleLength, throws, maxBoundary, NumberOfFloorboards);
            numLines = numel(x1Array);

            for j = 1:numLines
                if numeric_array(j) == 1
                    lineColor = 'r'; % Red for numeric_array equal to 1
                else
                    lineColor = 'b'; % Blue for numeric_array equal to 0
                end
                    plot(app.UIAxes, [x1Array(j), x2Array(j)], [y1Array(j), y2Array(j)], 'Color', lineColor);
            end

            hold(app.UIAxes, "off");

            lines = 0 : width : width * NumberOfFloorboards;
            
            for i = 1 : length(lines)
                xline(app.UIAxes,lines(i));
            end

            app.EsimatedPiValueTextArea.Value = num2str(p);


        end

        % Callback function: GenerateResultButton, UIAxes
        function GenerateResultButtonPushed(app, event)
            currentDropDownValue = app.SlectTaskDropDown.Value;
            cla(app.UIAxes)
            throws = app.NumberOfThrowsEditField.Value;
            needlength = app.LengthOfNeedleEditField.Value;
            width = app.WidthOfPlanksEditField.Value;
            
            NumberOfFloorboards = app.NumberOfPlanksEditField.Value;
            maxBoundary = width * NumberOfFloorboards;

            if strcmp(currentDropDownValue, 'BuffonsStandardPi - Task 1')

                hold(app.UIAxes, "on");
                [p,crossings, x1Array, y1Array, x2Array, y2Array, numeric_array] = Task4NeedleProblem(width,needlength,throws, maxBoundary, NumberOfFloorboards);
                numLines = numel(x1Array);
    
                for j = 1:numLines
                    if numeric_array(j) == 1
                        lineColor = 'r'; % Red for numeric_array equal to 1
                    else
                        lineColor = 'b'; % Blue for numeric_array equal to 0
                    end
                        plot(app.UIAxes, [x1Array(j), x2Array(j)], [y1Array(j), y2Array(j)], 'Color', lineColor);
                end
    
                hold(app.UIAxes, "off");
 
                lines = 0 : width : width * NumberOfFloorboards;
                
                for i = 1 : length(lines)
                    xline(app.UIAxes,lines(i));
                end
    
                app.EsimatedPiValueTextArea.Value = num2str(p);

            elseif strcmp(currentDropDownValue, 'BuffonsSquaresPi - Task 2') 

                hold(app.UIAxes, "on");
                [p, Corner1xArray, Corner1yArray, Corner2xArray, Corner2yArray, Corner3xArray, Corner3yArray, Corner4xArray, Corner4yArray, numeric_array] = Task4Squares(width,needlength,throws, maxBoundary);
                
                numSquares = numel(Corner1xArray);
    
                for j = 1:numSquares
                    if numeric_array(j) == 1
                        lineColor = 'r'; % Red for numeric_array equal to 1
                    else
                        lineColor = 'b'; % Blue for numeric_array equal to 0
                    end
                        xCorners = [Corner1xArray(j), Corner2xArray(j), Corner3xArray(j), Corner4xArray(j), Corner1xArray(j)];
                        yCorners = [Corner1yArray(j), Corner2yArray(j), Corner3yArray(j), Corner4yArray(j), Corner1yArray(j)];
                        plot(app.UIAxes, xCorners, yCorners, 'color', lineColor); % Plot the entire square

                end

                app.EsimatedPiValueTextArea.Value = num2str(p);
           
                hold(app.UIAxes, 'off');
    
               
                lines = 0 : width : width * NumberOfFloorboards;
                
                for i = 1 : length(lines)
                    xline(app.UIAxes,lines(i));
                end
                

            elseif strcmp(currentDropDownValue, 'Triangles')

                
                hold(app.UIAxes, "on");
                [p, Corner1xArray, Corner1yArray, Corner2xArray, Corner2yArray, Corner3xArray, Corner3yArray, numeric_array] = Triangles(width,needlength,throws, maxBoundary);
                
                numSquares = numel(Corner1xArray);
    
                for j = 1:numSquares
                    if numeric_array(j) == 1
                        lineColor = 'r'; % Red for numeric_array equal to 1
                    else
                        lineColor = 'b'; % Blue for numeric_array equal to 0
                    end
                        xCorners = [Corner1xArray(j), Corner2xArray(j), Corner3xArray(j),Corner1xArray(j) ];
                        yCorners = [Corner1yArray(j), Corner2yArray(j), Corner3yArray(j),Corner1yArray(j)];
                        plot(app.UIAxes, xCorners, yCorners, 'color', lineColor); % Plot the entire square

                end

                app.EsimatedPiValueTextArea.Value = num2str(p);
           
                hold(app.UIAxes, 'off');
    
               
                lines = 0 : width : width * NumberOfFloorboards;
                
                for i = 1 : length(lines)
                    xline(app.UIAxes,lines(i));
                end
            end

        end

        % Drop down opening function: SlectTaskDropDown
        function SlectTaskDropDownOpening(app, event)
            %app.SlectTaskDropDown.ValueChangedFcn = @app.updateSimulationType;
            currentDropDownValue = app.SlectTaskDropDown.Value;
        end

        % Value changed function: NumberOfPlanksEditField
        function NumberOfPlanksEditFieldValueChanged(app, event)
            numPlanks = app.NumberOfPlanksEditField.Value;
        end

        % Value changed function: NumberOfThrowsEditField
        function NumberOfThrowsEditFieldValueChanged(app, event)
            numThrows = app.NumberOfThrowsEditField.Value;
            
        end

        % Callback function
        function NumberofNeedlesSquaresEditFieldValueChanged(app, event)
            numNeedles = app.NumberofNeedlesSquaresEditField.Value;
            
        end

        % Value changed function: WidthOfPlanksEditField
        function WidthOfPlanksEditFieldValueChanged(app, event)
            value = app.WidthOfPlanksEditField.Value;
            
        end

        % Value changed function: EsimatedPiValueTextArea
        function EsimatedPiValueTextAreaValueChanged(app, event)
            value = app.EsimatedPiValueTextArea.Value;
            
        end

        % Value changed function: FontSizeofPiSlider
        function FontSizeofPiSliderValueChanged(app, event)
            fontSize = app.FontSizeofPiSlider.Value;
            app.EsimatedPiValueTextArea.FontSize = fontSize;
        end

        % Value changed function: FontColourDropDown
        function FontColourDropDownValueChanged(app, event)
            FontColour = app.FontColourDropDown.Value;
            set(app.EsimatedPiValueTextArea, 'FontColor', lower(FontColour));          

        end

        % Button pushed function: ClearGraphButton
        function ClearGraphButtonPushed(app, event)
            cla(app.UIAxes);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Buffons Needle Monte Carlo Simulation')
            xlabel(app.UIAxes, 'Width Of Planks')
            ylabel(app.UIAxes, 'Width Of Planks')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @GenerateResultButtonPushed, true);
            app.SimulationControlsPanel = uipanel(app.UIFigure);
            app.SimulationControlsPanel.Title = 'Simulation Controls';
            app.NumberOfPlanksEditFieldLabel = uilabel(app.SimulationControlsPanel);
            app.NumberOfPlanksEditFieldLabel.Text = 'Number Of Planks';
            app.NumberOfPlanksEditField = uieditfield(app.SimulationControlsPanel, 'numeric');
            app.NumberOfPlanksEditField.ValueChangedFcn = createCallbackFcn(app, @NumberOfPlanksEditFieldValueChanged, true);
            app.NumberOfPlanksEditField.Value = 1;
            app.NumberOfThrowsEditFieldLabel = uilabel(app.SimulationControlsPanel);
            app.NumberOfThrowsEditFieldLabel.Text = 'Number Of Throws';
            app.NumberOfThrowsEditField = uieditfield(app.SimulationControlsPanel, 'numeric');
            app.NumberOfThrowsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberOfThrowsEditFieldValueChanged, true);
            app.NumberOfThrowsEditField.Value = 100;
            app.SlectTaskDropDownLabel = uilabel(app.SimulationControlsPanel);
            app.SlectTaskDropDownLabel.Text = 'Slect Task';
            app.SlectTaskDropDown = uidropdown(app.SimulationControlsPanel);
            app.SlectTaskDropDown.Items = {'BuffonsStandardPi - Task 1', 'BuffonsSquaresPi - Task 2', 'Triangles'};
            app.SlectTaskDropDown.DropDownOpeningFcn = createCallbackFcn(app, @SlectTaskDropDownOpening, true);
            app.SlectTaskDropDown.Value = 'BuffonsStandardPi - Task 1';
            app.LengthOfNeedleEditFieldLabel = uilabel(app.SimulationControlsPanel);
            app.LengthOfNeedleEditFieldLabel.Text = 'Length Of Needle';
            app.LengthOfNeedleEditField = uieditfield(app.SimulationControlsPanel, 'numeric');
            app.LengthOfNeedleEditField.Limits = [0 Inf];
            app.LengthOfNeedleEditField.Value = 2;
            app.WidthOfPlanksEditFieldLabel = uilabel(app.SimulationControlsPanel);
            app.WidthOfPlanksEditFieldLabel.Text = 'Width Of Planks';
            app.WidthOfPlanksEditField = uieditfield(app.SimulationControlsPanel, 'numeric');
            app.WidthOfPlanksEditField.ValueChangedFcn = createCallbackFcn(app, @WidthOfPlanksEditFieldValueChanged, true);
            app.WidthOfPlanksEditField.Value = 10;
            app.GenerateResultButton = uibutton(app.SimulationControlsPanel, 'push');
            app.GenerateResultButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateResultButtonPushed, true);
            app.GenerateResultButton.Text = 'Generate Result';
            app.EsimatedPiValueTextAreaLabel = uilabel(app.SimulationControlsPanel);
            app.EsimatedPiValueTextAreaLabel.Text = 'Esimated Pi Value';
            app.EsimatedPiValueTextArea = uitextarea(app.SimulationControlsPanel);
            app.EsimatedPiValueTextArea.ValueChangedFcn = createCallbackFcn(app, @EsimatedPiValueTextAreaValueChanged, true);
            app.ClearGraphButton = uibutton(app.SimulationControlsPanel, 'push');
            app.ClearGraphButton.ButtonPushedFcn = createCallbackFcn(app, @ClearGraphButtonPushed, true);
            app.ClearGraphButton.Text = 'Clear Graph';
            app.FontSizeofPiSliderLabel = uilabel(app.UIFigure);
            app.FontSizeofPiSliderLabel.Text = 'Font Size of Pi';
            app.FontSizeofPiSlider = uislider(app.UIFigure);
            app.FontSizeofPiSlider.Limits = [10 35];
            app.FontSizeofPiSlider.ValueChangedFcn = createCallbackFcn(app, @FontSizeofPiSliderValueChanged, true);
            app.FontSizeofPiSlider.Value = 15;
            app.FontColourDropDownLabel = uilabel(app.UIFigure);
            app.FontColourDropDownLabel.Text = 'Font Colour';
            app.FontColourDropDown = uidropdown(app.UIFigure);
            app.FontColourDropDown.Items = {'Black', 'Green', 'Yellow', 'Red', 'Blue', 'Cyan'};
            app.FontColourDropDown.ValueChangedFcn = createCallbackFcn(app, @FontColourDropDownValueChanged, true);
            app.FontColourDropDown.Value = 'Black';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Task4GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end